classdef SmartGrid_03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMARTGRIDUIFigure          matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SetupTab                   matlab.ui.container.Tab
        TEAM30Label                matlab.ui.control.Label
        SMARTGRIDLabel             matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        DAQLamp                    matlab.ui.control.Lamp
        ConnectDAQ                 matlab.ui.control.Button
        RecordDAQ_2                matlab.ui.control.Button
        DataTime_2                 matlab.ui.control.NumericEditField
        CollectionTimesecEditFieldLabel_3  matlab.ui.control.Label
        Vsig                       matlab.ui.control.UIAxes
        Isig                       matlab.ui.control.UIAxes
        IFT                        matlab.ui.control.UIAxes
        VFT                        matlab.ui.control.UIAxes
        DataAnalysisTab            matlab.ui.container.Tab
        SaveDataButton             matlab.ui.control.Button
        DataTime                   matlab.ui.control.NumericEditField
        CollectionTimesecEditFieldLabel_2  matlab.ui.control.Label
        RMSCurrent                 matlab.ui.control.NumericEditField
        RMSCurrentAEditFieldLabel  matlab.ui.control.Label
        RMSVoltage                 matlab.ui.control.NumericEditField
        RMSVoltageVEditFieldLabel  matlab.ui.control.Label
        Phase                      matlab.ui.control.NumericEditField
        PhasedegreesLabel          matlab.ui.control.Label
        PowerFactor                matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel  matlab.ui.control.Label
        PhaserCalculation          matlab.ui.control.Button
        RecordDAQ                  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        CurrentDisplay             matlab.ui.control.UIAxes
        VoltageDisplay             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DAQobj; % object for DAQ connection
        daq_test; % most recent 'daq test' data
        v_data;
        i_data;
        t_data;

        PICOobj; % object for picoscope
        PConfig;
        PMethods;
        PEnum;
        PStructs;
        channelA;
        channelB;
        chARangeMv;
        chBRangeMv;
        maxADCCount;
        PstreamObj;
        PblockObj;
        PtrigObj;
        Pstatus;
        pflag; % streaming flag

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectDAQ
        function ConnectDAQPushed(app, event)
            app.DAQobj = daq('ni');
            addinput(app.DAQobj,'myDAQ2','ai0','Voltage'); % voltage signal from sensor
            addinput(app.DAQobj,'myDAQ2','ai1','Voltage'); % current signal from sensor
            chantable = app.DAQobj.Channels;
            % if the connection was successful, two channels should be open
            if length(chantable) == 2 % connected
                app.DAQLamp.Color = [0.39 0.83 0.07]; % green
                app.ConnectDAQ.Enable = 'off';
            else % connection failed
                app.DAQLamp.Color = [0.85 0.33 0.10]; % red
                delete(app.DAQobj); % cancel
            end
            compass(app.UIAxes, 0, 0);
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.pflag = 0;
        end

        % Button pushed function: RecordDAQ
        function RecordDAQPushed(app, event)
            acq_length_sec = app.DataTime.Value; % sets time length for data acquisition
            acq_length_ms = acq_length_sec*1000; % converts seconds to milliseconds
            app.daq_test = read(app.DAQobj,seconds(acq_length_sec),'OutputFormat','Matrix'); % reads data from myDAQ

            % grab current and voltage, scale properly from sensor board
            app.i_data = (app.daq_test(:,1) - 1.2) * 10; 
            app.v_data = (app.daq_test(:,2) - 1.2) * 10;
            i_avg = mean(app.i_data);
            app.i_data = app.i_data - i_avg; 

            % plots current and voltage with respect to time
            app.t_data = 0:(acq_length_ms-1);
            plot(app.VoltageDisplay,app.t_data,app.v_data)
            xlim(app.VoltageDisplay,[0 acq_length_ms])
            plot(app.CurrentDisplay,app.t_data,app.i_data)
            xlim(app.CurrentDisplay,[0 acq_length_ms])
        end

        % Button pushed function: PhaserCalculation
        function PhaserCalculationPushed(app, event)
%        Use of phdiffmeasure provided by:
%           Copyright (c) 2016, Hristo Zhivomirov
%           All rights reserved.
% 
%         Redistribution and use in source and binary forms, with or without
%         modification, are permitted provided that the following conditions are met:
% 
%         * Redistributions of source code must retain the above copyright notice, this
%          list of conditions and the following disclaimer.
% 
%         * Redistributions in binary form must reproduce the above copyright notice,
%           this list of conditions and the following disclaimer in the documentation
%          and/or other materials provided with the distribution
%         * Neither the name of  nor the names of its contributors may be 
%           used to endorse or promote products derived from this
%           software without specific prior written permission.
%         THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
%         AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
%         IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE
%         DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE LIABLE
%         FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL
%         DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR
%         SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER
%         CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY,
%         OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE
%         OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.

            phase = rad2deg(phdiffmeasure(app.v_data, app.i_data)); % measures the phase difference in degrees
           if (phase > 180)
            phase = phase - 360; % adjusts the phase difference by 360 to get lagging/leading current
           end
           power_factor = cos(deg2rad(phase)); % calculates power factor
           app.PowerFactor.Value = power_factor;
           app.Phase.Value = phase;
           v_max = max(app.v_data); 
           i_max = max(app.i_data);
           v_rms = v_max / sqrt(2); % calculates the rms voltage from the max voltage
           i_rms = i_max / sqrt(2); % calculates the rms current from the max current
           app.RMSCurrent.Value = i_rms;
           app.RMSVoltage.Value = v_rms;

           % sets the axis for the phase plot
           set(app.UIAxes, 'XLim', [-20 20]);
           set(app.UIAxes, 'YLim', [-20 20]);

           grid(app.UIAxes,'on');

           % finds the x and y coordinates for the current phasor
           x = i_rms * cos(deg2rad(phase));
           y = i_rms * sin(deg2rad(phase));

           %establishes the compass graph to get a readable phasor diagram
           c = compass(app.UIAxes, [v_rms x*15], [0 y*15]);
           c1 = c(1);
           c1.Color = 'r';
           legend(app.UIAxes, ["Voltage", "Current"]);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Save most recent DAQ data
            raw_data = app.daq_test; % data directly from DAQ
            real_data = [app.v_data,app.i_data]; % adjusted/scaled data
            time_ms = app.t_data; % time data was collected over
            [f,p] = uiputfile('*.mat');
            if f~=0
                save([p f],'raw_data','real_data','time_ms');
            end
        end

        % Close request function: SMARTGRIDUIFigure
        function SMARTGRIDUIFigureCloseRequest(app, event)
            if strcmp(app.ConnectDAQ.Enable,'off')
                delete(app.DAQobj)
            end
            delete(app)
        end

        % Button pushed function: RecordDAQ_2
        function RecordDAQ_2ButtonPushed(app, event)
            acq_length_sec = app.DataTime_2.Value; % sets time length for data acquisition
            acq_length_ms = acq_length_sec*1000; % converts seconds to milliseconds
            testdata = read(app.DAQobj,seconds(acq_length_sec),'OutputFormat','Matrix'); % reads data from myDAQ
            
            % grab current and voltage, scale properly from sensor board
            i_dat = (testdata(:,1) - 1.2) * 10;
            v_dat = (testdata(:,2) - 1.2) * 10;
            i_avg = mean(i_dat);
            i_dat = i_dat - i_avg;

            % plots current and voltage with respect to time
            t_dat = 0:(acq_length_ms-1);
            plot(app.Vsig,t_dat,v_dat)
            xlim(app.Vsig,[0 acq_length_ms])
            plot(app.Isig,t_dat,i_dat)
            xlim(app.Isig,[0 acq_length_ms])
            
            %computes Fourier Transform to get Frequency Data
            time = t_dat * .001;
            sample_freq = 1/time(2);
            sigA = v_dat;
            sigB = i_dat;

            numa = length(sigA); % finds the number of data points
            Ya = fft(sigA); % evaluates the fourier transform on the data
            P2a = abs(Ya / numa);
            P1a = P2a(1:numa/2 + 0.5);
            P1a(2:end-1) = 2 * P1a(2:end - 1);
            fa = sample_freq * (0:(numa / 2)) / numa;
            [~, Ia] = max(P1a);
            freqa = fa(Ia);

            numb = length(sigB); % finds the number of data points
            Yb = fft(sigB); % evaluates the fourier transform on the data
            P2b = abs(Yb / numb);
            P1b = P2b(1:numb/2 + 0.5);
            P1b(2:end-1) = 2 * P1b(2:end - 1);
            fb = sample_freq * (0:(numb / 2)) / numb;
            [~, Ib] = max(P1b);
            freq = fb(Ib);
            
            len = length(t_dat);
            disp(size(fa))
            disp(size(P1a))
            stem(app.VFT,fa(1, 1:length(P1a)),P1a)
            xlim(app.VFT,[0 .4*len])
            text(app.VFT,fa(Ia), P1a(Ia), "    Frequency: " + freqa + " Hz")
            stem(app.IFT,fb(1, 1:length(P1b)),P1b)
            xlim(app.IFT,[0 .4*len])
            text(app.IFT,fb(Ib), P1b(Ib), "    Frequency: " + freq + " Hz")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMARTGRIDUIFigure = uifigure('Visible', 'off');
            app.SMARTGRIDUIFigure.Name = 'SMARTGRID';
            app.SMARTGRIDUIFigure.CloseRequestFcn = createCallbackFcn(app, @SMARTGRIDUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SMARTGRIDUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.VFT = uiaxes(app.SetupTab);
            title(app.VFT, 'Voltage Fourier Transform')
            xlabel(app.VFT, 'Frequency [Hz]')
            ylabel(app.VFT, 'Amplitude [a.u.]')
            app.IFT = uiaxes(app.SetupTab);
            title(app.IFT, 'Current Fourier Transform')
            xlabel(app.IFT, 'Frequency [Hz]')
            ylabel(app.IFT, 'Amplitude [a.u.]')
            app.Isig = uiaxes(app.SetupTab);
            title(app.Isig, 'Current')
            xlabel(app.Isig, 'Time [ms]')
            ylabel(app.Isig, 'Signal [A]')
            app.Vsig = uiaxes(app.SetupTab);
            title(app.Vsig, 'Voltage')
            xlabel(app.Vsig, 'Time [ms]')
            ylabel(app.Vsig, 'Signal [V]')
            app.CollectionTimesecEditFieldLabel_3 = uilabel(app.SetupTab);
            app.CollectionTimesecEditFieldLabel_3.Text = 'Collection Time (sec)';
            app.DataTime_2 = uieditfield(app.SetupTab, 'numeric');
            app.DataTime_2.Value = 0.2;
            app.RecordDAQ_2 = uibutton(app.SetupTab, 'push');
            app.RecordDAQ_2.ButtonPushedFcn = createCallbackFcn(app, @RecordDAQ_2ButtonPushed, true);
            app.RecordDAQ_2.Text = 'Record DAQ';
            app.ButtonGroup = uibuttongroup(app.SetupTab);
            app.ConnectDAQ = uibutton(app.ButtonGroup, 'push');
            app.ConnectDAQ.ButtonPushedFcn = createCallbackFcn(app, @ConnectDAQPushed, true);
            app.ConnectDAQ.Text = 'Connect DAQ';
            app.DAQLamp = uilamp(app.ButtonGroup);
            app.SMARTGRIDLabel = uilabel(app.SetupTab);
            app.SMARTGRIDLabel.Text = 'SMART GRID';
            app.TEAM30Label = uilabel(app.SetupTab);
            app.TEAM30Label.Text = 'TEAM 30';
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.VoltageDisplay = uiaxes(app.DataAnalysisTab);
            title(app.VoltageDisplay, 'Voltage')
            xlabel(app.VoltageDisplay, 'Time [ms]')
            ylabel(app.VoltageDisplay, 'Signal [V]')
            app.CurrentDisplay = uiaxes(app.DataAnalysisTab);
            title(app.CurrentDisplay, 'Current')
            xlabel(app.CurrentDisplay, 'Time [ms]')
            ylabel(app.CurrentDisplay, 'Signal [A]')
            app.UIAxes = uiaxes(app.DataAnalysisTab);
            title(app.UIAxes, 'Phase Diagram')
            zlabel(app.UIAxes, 'Z')
            app.RecordDAQ = uibutton(app.DataAnalysisTab, 'push');
            app.RecordDAQ.ButtonPushedFcn = createCallbackFcn(app, @RecordDAQPushed, true);
            app.RecordDAQ.Text = 'Record DAQ';
            app.PhaserCalculation = uibutton(app.DataAnalysisTab, 'push');
            app.PhaserCalculation.ButtonPushedFcn = createCallbackFcn(app, @PhaserCalculationPushed, true);
            app.PhaserCalculation.Text = 'Phaser Calculation';
            app.PowerFactorEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.PowerFactorEditFieldLabel.Text = 'Power Factor';
            app.PowerFactor = uieditfield(app.DataAnalysisTab, 'numeric');
            app.PhasedegreesLabel = uilabel(app.DataAnalysisTab);
            app.PhasedegreesLabel.Text = 'Phase (degrees)';
            app.Phase = uieditfield(app.DataAnalysisTab, 'numeric');
            app.RMSVoltageVEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.RMSVoltageVEditFieldLabel.Text = 'RMS Voltage (V)';
            app.RMSVoltage = uieditfield(app.DataAnalysisTab, 'numeric');
            app.RMSCurrentAEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.RMSCurrentAEditFieldLabel.Text = 'RMS Current (A)';
            app.RMSCurrent = uieditfield(app.DataAnalysisTab, 'numeric');
            app.CollectionTimesecEditFieldLabel_2 = uilabel(app.DataAnalysisTab);
            app.CollectionTimesecEditFieldLabel_2.Text = 'Collection Time (sec)';
            app.DataTime = uieditfield(app.DataAnalysisTab, 'numeric');
            app.DataTime.Value = 0.5;
            app.SaveDataButton = uibutton(app.DataAnalysisTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.SMARTGRIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartGrid_03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMARTGRIDUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMARTGRIDUIFigure)
        end
    end
end